classdef NodeCommandsTestGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        ListBox         matlab.ui.control.ListBox
        ListBoxLabel    matlab.ui.control.Label
        EditField       matlab.ui.control.EditField
        EditFieldLabel  matlab.ui.control.Label
        Button          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if (app.ListBox.Value == "Serial write")
                serialSend(COMPort, app.EditField.Value); % send the data in the EditField to the master of the nodes.
            elseif (app.ListBox.Value == "Serial receive")
                % add serial receive function.
            elseif (app.ListBox.Value == "Measurement")
                serialSend(COMPort, 'M');
            elseif (app.ListBox.Value == "Localization")
                serialSend(COMPort, 'C');
            elseif (app.ListBox.Value == "Test nodes")
                serialSend(COMPort, 'T');
            elseif (app.ListBox.Value == "Open COMPort")
                portList = serialportlist();
                disp(portList)
                % add open comport function.
            elseif (app.ListBox.Value == "Close COMPort")
                % add close comport function.
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.EditField.Value = app.EditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Serial send', 'Serial receive', 'Measurement', 'Localization', 'Test nodes', 'Open COMPort', 'Close COMPort'};
            app.ListBox.Value = 'Test nodes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NodeCommandsTestGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end